classdef FCS_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        pushbutton1  matlab.ui.control.Button
        pushbutton2  matlab.ui.control.Button
        checkbox1    matlab.ui.control.CheckBox
        pushbutton3  matlab.ui.control.Button
    end

    
    methods (Access = private)
        function edit1_Callback(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit1 as text
            %        str2double(get(hObject,'String')) returns contents of edit1 as a double
            
            infile = get(hObject,'string');
                     app.edit1 = infile;
        end
        
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function FCS_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to FCS (see VARARGIN)
%             
%             % Choose default command line output for FCS
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            % hObject    handle to checkbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox1
            
            trip = get(hObject,'Value');
                     app.checkbox1 = trip;
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            trip1 = get(app.checkbox1,'Value');
            %trip1=char(trip1);
            %trip1=str2num(trip1);
            
            if trip1==1
            %Code to fit data from input file to FCS autocorrelation curve
            %Inputs:
            %infile is the path to the input file
            %incolumn is the column to use for the analysis (1 = Red/Acceptor channel,
            %   2 = Green/Donor channel)
            %binning is the time binning of the data to use
            %
            %Output:
            %outvector is a list of the fitted parameters from the analysis
            
            infile2 = get(app.pushbutton3,'UserData');
            infile2=char(infile2);
            binning=1;
            %Read in tab-delimited file (change the '\t' to other delimiter if
            %   necessary)
            inread=dlmread(infile2,'\t');
            incolumn=1;
            %Choose the correct column
            indata=inread(:,incolumn);
            [filepath,name,ext] = fileparts(infile2)
            infile=name
            inpath=filepath
            indata=indata';
            
            %indata=indata(1:3000000);
            
            m=ceil(length(indata)/binning);
            f=zeros(1,m+1);
            for n=1:length(indata)
                m=ceil(n/binning);
                f(m)=f(m)+indata(n);
            end
            
            %Calculate how many points to compute the autocorrelation for
            nlags=m/10;
            
            %The point after which the correlation should be down to 0
            zerolength=nlags/2;
            
            %Calculate the autocorrelation function
            [corrcoeffs,lags]=autocorr(f,nlags,zerolength,0);
            lags=lags';
            
            lags=lags(2:size(lags));
            corrcoeffs=corrcoeffs';
            corrcoeffs=corrcoeffs(2:size(corrcoeffs));
            lags=10*lags;
            %lags=lags(7:size(lags));
            %corrcoeffs=corrcoeffs(7:size(corrcoeffs));
            %lags=lags/1000000;
            %corrcoeffs=(corrcoeffs-min(corrcoeffs))/(max(corrcoeffs)-min(corrcoeffs));
            
            %Fit the data with the FCS equation
            ft=fittype('(1+(tp/(1-tp))*exp(-x/te))*g0./((1+(x/td)).*(1+a^(-2)*(x/td))^(1/2))+ginf');
            %ft=fittype(fcsequation);
            [fcsfit,gof,fitinfo]=fit(lags,corrcoeffs,ft,'StartPoint',[7,0.1,0.001,1000,10,0.1],'Lower',[3,0.00005,10^-6,1,10^-6,10^-9],'Upper',[20,1.5,0.01,100000,25,0.5]);
            %plot(fcsfit,lags,corrcoeffs);
            
            %Output fitted values in order (a,g0,ginf,td)
            %outvector=coeffvalues(fcsfit);
            %outvector=fitinfo.residuals;
            %outvector=[corrcoeffs];
            %outvector=fcsfit
            
            %Identify outliers and exclude from fit
            fitinfo;
            residuals=fitinfo.residuals;
            I = abs( residuals) > 50 * std( residuals );
            outliers = excludedata(lags,corrcoeffs,'indices',I);
            [fit2,gof,fitinfo] = fit(lags,corrcoeffs,ft,'StartPoint',[8.840,0.1,0.001,100,10,0.1],'Lower',[3,0.00005,10^-8,1,10^-6,10^-9],'Upper',[100,1.5,0.1,100000,10,0.1],'Exclude',outliers);
            
            %Plot fit with points excluded
            
            mkdir(strcat(filepath,'/figures/'));
            outpathtot=strcat(filepath,'/figures/',name,'FCSonecomptriplet')
            %% Plot your raw data (2 colours) and export to pdf
            %acceptorrev=-acceptor;
            figure;
            hold on;
            plot(fit2,lags,corrcoeffs)
            ax=gca
            ax.XScale = 'log'
            xlabel('lags')
            ylabel('autocorrelation')
            title(name, 'Interpreter', 'none')
            hold off
             export_fig(outpathtot,'-pdf')
            
            %%
            %plot(fcsfit,lags,corrcoeffs)
            
            outvector1=coeffvalues(fit2);
            outvector2=gof.rmse;
            outvector=[outvector1(:,1),outvector1(:,2),outvector1(:,3),outvector1(:,4),outvector1(:,5),outvector1(:,6),outvector2(:,1)];
            %outvector=fit2;
            %outvector=gof.rmse;
            
            outvector=num2cell(outvector);
            outvecttot={'a (z0/w0)','G0','Ginf','Diffusion Time','Te','Tp','r.m.s.e.'};
            outvecttot=[outvecttot;outvector];
            mkdir(filepath,'/FCS/')
            outpath=strcat(filepath,'/FCS/',name,'FCSonecomptriplet');
            xlswrite(outpath,outvecttot);
            
            
            %dlmwrite('testautocorr',outvector,'delimiter','\t','newline','pc','precision','%.6f');
            end
            
            if trip1==0
            %Code to fit data from input file to FCS autocorrelation curve
            %Inputs:
            %infile is the path to the input file
            %incolumn is the column to use for the analysis (1 = Red/Acceptor channel,
            %   2 = Green/Donor channel)
            %binning is the time binning of the data to use
            %
            %Output:
            %outvector is a list of the fitted parameters from the analysis
            
            infile2 = get(app.pushbutton3,'UserData');
            infile2=char(infile2);
            binning=1;
            %Read in tab-delimited file (change the '\t' to other delimiter if
            %   necessary)
            inread=dlmread(infile2,'\t');
            incolumn=1;
            %Choose the correct column
            indata=inread(:,incolumn);
            [filepath,name,ext] = fileparts(infile2)
            infile=name
            inpath=filepath
            indata=indata';
            
            %indata=indata(1:3000000);
            
            m=ceil(length(indata)/binning);
            f=zeros(1,m+1);
            for n=1:length(indata)
                m=ceil(n/binning);
                f(m)=f(m)+indata(n);
            end
            
            %Calculate how many points to compute the autocorrelation for
            nlags=m/10;
            
            %The point after which the correlation should be down to 0
            zerolength=nlags/2;
            
            %Calculate the autocorrelation function
            [corrcoeffs,lags]=autocorr(f,nlags,zerolength,0);
            lags=lags';
            
            lags=lags(2:size(lags));
            corrcoeffs=corrcoeffs';
            corrcoeffs=corrcoeffs(2:size(corrcoeffs));
            lags=10*lags;
            %lags=lags(7:size(lags));
            %corrcoeffs=corrcoeffs(7:size(corrcoeffs));
            %lags=lags/1000000;
            %corrcoeffs=(corrcoeffs-min(corrcoeffs))/(max(corrcoeffs)-min(corrcoeffs));
            
            %Fit the data with the FCS equation
            ft=fittype('(1+(tp/(1-tp))*exp(-x/te))*g0./((1+(x/td)).*(1+a^(-2)*(x/td))^(1/2))+ginf');
            %ft=fittype(fcsequation);
            [fcsfit,gof,fitinfo]=fit(lags,corrcoeffs,ft,'StartPoint',[7,0.1,0.001,1000,10^-8,10^-8],'Lower',[3,0.00005,10^-6,1,10^-9,10^-9],'Upper',[20,1.5,0.01,100000,10^-6,10^-6]);
            %plot(fcsfit,lags,corrcoeffs);
            
            %Output fitted values in order (a,g0,ginf,td)
            %outvector=coeffvalues(fcsfit);
            %outvector=fitinfo.residuals;
            %outvector=[corrcoeffs];
            %outvector=fcsfit
            
            %Identify outliers and exclude from fit
            fitinfo;
            residuals=fitinfo.residuals;
            I = abs( residuals) > 50 * std( residuals );
            outliers = excludedata(lags,corrcoeffs,'indices',I);
            [fit2,gof,fitinfo] = fit(lags,corrcoeffs,ft,'StartPoint',[8.840,0.1,0.001,100,10^-8,10^-8],'Lower',[3,0.00005,10^-8,1,10^-9,10^-9],'Upper',[100,1.5,0.1,100000,10^-6,10^-6],'Exclude',outliers);
            
            %Plot fit with points excluded
            
            mkdir(strcat(filepath,'/figures/'));
            outpathtot=strcat(filepath,'/figures/',name,'FCSonecompnotriplet')
            %% Plot your raw data (2 colours) and export to pdf
            %acceptorrev=-acceptor;
            figure;
            hold on;
            plot(fit2,lags,corrcoeffs)
            ax=gca
            ax.XScale = 'log'
            xlabel('lags')
            ylabel('autocorrelation')
            title(name, 'Interpreter', 'none')
            hold off
             export_fig(outpathtot,'-pdf')
            
            %%
            %plot(fcsfit,lags,corrcoeffs)
            
            outvector1=coeffvalues(fit2);
            outvector2=gof.rmse;
            outvector=[outvector1(:,1),outvector1(:,2),outvector1(:,3),outvector1(:,4),outvector2(:,1)];
            %outvector=fit2;
            %outvector=gof.rmse;
            
            outvector=num2cell(outvector);
            outvecttot={'a (z0/w0)','G0','Ginf','Diffusion Time','r.m.s.e.'};
            outvecttot=[outvecttot;outvector];
            mkdir(filepath,'/FCS/')
            outpath=strcat(filepath,'/FCS/',name,'FCSonecompnotriplet');
            xlswrite(outpath,outvecttot);
            
            
            %dlmwrite('testautocorr',outvector,'delimiter','\t','newline','pc','precision','%.6f');
            end
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            trip1 = get(app.checkbox1,'Value');
            %trip1=char(trip1);
            %trip1=str2num(trip1);
            
            if trip1==1
            %Code to fit data from input file to FCS autocorrelation curve
            %Inputs:
            %infile is the path to the input file
            %incolumn is the column to use for the analysis (1 = Red/Acceptor channel,
            %   2 = Green/Donor channel)
            %binning is the time binning of the data to use
            %
            %Output:
            %outvector is a list of the fitted parameters from the analysis
            
            infile2 = get(app.pushbutton3,'UserData');
            infile2=char(infile2);
            binning=1;
            %Read in tab-delimited file (change the '\t' to other delimiter if
            %   necessary)
            inread=dlmread(infile2,'\t');
            [filepath,name,ext] = fileparts(infile2)
            infile=name
            inpath=filepath
            incolumn=1;
            %Choose the correct column
            indata=inread(:,incolumn);
            
            indata=indata';
            
            %indata=indata(1:3000000);
            
            m=ceil(length(indata)/binning);
            f=zeros(1,m+1);
            for n=1:length(indata)
                m=ceil(n/binning);
                f(m)=f(m)+indata(n);
            end
            
            %Calculate how many points to compute the autocorrelation for
            nlags=m/10;
            
            %The point after which the correlation should be down to 0
            zerolength=nlags/2;
            
            %Calculate the autocorrelation function
            [corrcoeffs,lags]=autocorr(f,nlags,zerolength,0);
            lags=lags';
            
            lags=lags(2:size(lags));
            corrcoeffs=corrcoeffs';
            corrcoeffs=corrcoeffs(2:size(corrcoeffs));
            lags=10*lags;
            %lags=lags(7:size(lags));
            %corrcoeffs=corrcoeffs(7:size(corrcoeffs));
            %lags=lags/1000000;
            %corrcoeffs=(corrcoeffs-min(corrcoeffs))/(max(corrcoeffs)-min(corrcoeffs));
            
            %Fit the data with the FCS equation
            ft=fittype('(1+(tp/(1-tp))*exp(-x/te))*g0.*(f/(1+(x/td)).*1/(1+a^(-2)*(x/td))^(1/2)+((1-f)/(1+x/t2))*1/(1+a^(-2)*(x/t2))^(1/2))+ginf');
            %ft=fittype(fcsequation);
            [fcsfit,gof,fitinfo]=fit(lags,corrcoeffs,ft,'StartPoint',[5,0.8,0.1,0.001,10,1000,1,0.1],'Lower',[3,0.01,0.00005,10^-6,1,1,1,10^-9],'Upper',[20,0.99,1.5,0.01,1000,10000,5,0.8]);
            %plot(fcsfit,lags,corrcoeffs);
            
            %Output fitted values in order (a,g0,ginf,td)
            %outvector=coeffvalues(fcsfit);
            %outvector=fitinfo.residuals;
            %outvector=[corrcoeffs];
            %outvector=fcsfit
            
            %Identify outliers and exclude from fit
            fitinfo;
            residuals=fitinfo.residuals;
            I = abs( residuals) > 50 * std( residuals );
            outliers = excludedata(lags,corrcoeffs,'indices',I);
            [fit2,gof,fitinfo]=fit(lags,corrcoeffs,ft,'StartPoint',[8.840,0.8,0.1,0.001,1000,10000,1,0.1],'Lower',[2,0.01,0.00005,10^-9,10,1,1,10^-9],'Upper',[100,0.99,1.5,0.01,10000,100000,1,0.1],'Exclude',outliers);
            
            %Plot fit with points excluded
            
            mkdir(strcat(filepath,'/figures/'));
            outpathtot=strcat(filepath,'/figures/',name,'FCStwocomptriplet')
            %% Plot your raw data (2 colours) and export to pdf
            %acceptorrev=-acceptor;
            figure;
            hold on;
            plot(fit2,lags,corrcoeffs)
            ax=gca
            ax.XScale = 'log'
            xlabel('lags')
            ylabel('autocorrelation')
            title(name, 'Interpreter', 'none')
            hold off
             export_fig(outpathtot,'-pdf')
            
            %%
            %plot(fcsfit,lags,corrcoeffs)
            
            outvector1=coeffvalues(fit2);
            outvector2=gof.rmse;
            outvector=[outvector1(:,1),outvector1(:,2),outvector1(:,3),outvector1(:,4),outvector1(:,6),outvector1(:,5),outvector1(:,7),outvector1(:,8),outvector2(:,1)];%outvector=fit2;
            %outvector=gof.rmse;
            
            outvector=num2cell(outvector);
            outvecttot={'a (z0/w0)', 'Fraction Component 1' 'G0','Ginf','Component 1 Diffusion Time','Component 2 Diffusion Time','Te','Tp','r.m.s.e.'};
            outvecttot=[outvecttot;outvector];
            mkdir(filepath,'/FCS/')
            outpath=strcat(filepath,'/FCS/',name,'FCStwocomptriplet');
            xlswrite(outpath,outvecttot);
            
            
            %dlmwrite('testautocorr',outvector,'delimiter','\t','newline','pc','precision','%.6f');
            end
            
            if trip1==0
            %Code to fit data from input file to FCS autocorrelation curve
            %Inputs:
            %infile is the path to the input file
            %incolumn is the column to use for the analysis (1 = Red/Acceptor channel,
            %   2 = Green/Donor channel)
            %binning is the time binning of the data to use
            %
            %Output:
            %outvector is a list of the fitted parameters from the analysis
            
            infile2 = get(app.pushbutton3,'UserData');
            infile2=char(infile2);
            binning=1;
            %Read in tab-delimited file (change the '\t' to other delimiter if
            %   necessary)
            inread=dlmread(infile2,'\t');
            incolumn=1;
            %Choose the correct column
            indata=inread(:,incolumn);
            [filepath,name,ext] = fileparts(infile2)
            infile=name
            inpath=filepath
            indata=indata';
            
            %indata=indata(1:3000000);
            
            m=ceil(length(indata)/binning);
            f=zeros(1,m+1);
            for n=1:length(indata)
                m=ceil(n/binning);
                f(m)=f(m)+indata(n);
            end
            
            %Calculate how many points to compute the autocorrelation for
            nlags=m/10;
            
            %The point after which the correlation should be down to 0
            zerolength=nlags/2;
            
            %Calculate the autocorrelation function
            [corrcoeffs,lags]=autocorr(f,nlags,zerolength,0);
            lags=lags';
            
            lags=lags(2:size(lags));
            corrcoeffs=corrcoeffs';
            corrcoeffs=corrcoeffs(2:size(corrcoeffs));
            lags=10*lags;
            %lags=lags(7:size(lags));
            %corrcoeffs=corrcoeffs(7:size(corrcoeffs));
            %lags=lags/1000000;
            %corrcoeffs=(corrcoeffs-min(corrcoeffs))/(max(corrcoeffs)-min(corrcoeffs));
            
            %Fit the data with the FCS equation
            ft=fittype('(1+(tp/(1-tp))*exp(-x/te))*g0.*(f/(1+(x/td)).*1/(1+a^(-2)*(x/td))^(1/2)+((1-f)/(1+x/t2))*1/(1+a^(-2)*(x/t2))^(1/2))+ginf');
            %ft=fittype(fcsequation);
            [fcsfit,gof,fitinfo]=fit(lags,corrcoeffs,ft,'StartPoint',[5,0.8,0.1,0.001,10,1000,10^-8,10^-8],'Lower',[3,0.01,0.00005,10^-6,1,1,10^-9,10^-9],'Upper',[20,0.99,1.5,0.01,1000,10000,10^-6,10^-6]);
            %plot(fcsfit,lags,corrcoeffs);
            
            %Output fitted values in order (a,g0,ginf,td)
            %outvector=coeffvalues(fcsfit);
            %outvector=fitinfo.residuals;
            %outvector=[corrcoeffs];
            %outvector=fcsfit
            
            %Identify outliers and exclude from fit
            fitinfo;
            residuals=fitinfo.residuals;
            I = abs( residuals) > 50 * std( residuals );
            outliers = excludedata(lags,corrcoeffs,'indices',I);
            [fit2,gof,fitinfo]=fit(lags,corrcoeffs,ft,'StartPoint',[8.840,0.8,0.1,0.001,1000,10000,10^-8,10^-8],'Lower',[2,0.01,0.00005,10^-9,10,1,10^-9,10^-9],'Upper',[100,0.99,1.5,0.01,10000,100000,10^-6,10^-6],'Exclude',outliers);
            
            %Plot fit with points excluded
            
            mkdir(strcat(filepath,'/figures/'));
            outpathtot=strcat(filepath,'/figures/',name,'FCStwocompnotriplet')
            %% Plot your raw data (2 colours) and export to pdf
            %acceptorrev=-acceptor;
            figure;
            hold on;
            plot(fit2,lags,corrcoeffs)
            ax=gca
            ax.XScale = 'log'
            xlabel('lags')
            ylabel('autocorrelation')
            title(name, 'Interpreter', 'none')
            hold off
             export_fig(outpathtot,'-pdf')
            
            %%
            %plot(fcsfit,lags,corrcoeffs)
            
            outvector1=coeffvalues(fit2);
            outvector2=gof.rmse;
            outvector=[outvector1(:,1),outvector1(:,2),outvector1(:,3),outvector1(:,4),outvector1(:,6),outvector1(:,5),outvector2(:,1)];%outvector=fit2;
            %outvector=gof.rmse;
            
            outvector=num2cell(outvector);
            outvecttot={'a (z0/w0)', 'Fraction Component 1' 'G0','Ginf','Component 1 Diffusion Time','Component 2 Diffusion Time','r.m.s.e.'};
            outvecttot=[outvecttot;outvector];
            mkdir(filepath,'/FCS/')
            outpath=strcat(filepath,'/FCS/',name,'FCStwocompnotriplet');
            xlswrite(outpath,outvecttot);
            
            
            %dlmwrite('testautocorr',outvector,'delimiter','\t','newline','pc','precision','%.6f');
            end
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [filename,pathname] = uigetfile('*.*');
            infile = strcat(pathname,filename);           
            %set(app.pushbutton3, 'UserData', infile);
                     app.pushbutton3.UserData = infile;
            %disp(hObject.String);
              % handles = guihandles(hObject);
                app.pushbutton3.Text = filename;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'FCS';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Text = 'One Species';
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Text = 'Two Species';
            app.checkbox1 = uicheckbox(app.figure1);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Text = 'Correction for Triplet State';
            app.checkbox1.Value = true;
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Text = 'Choose File';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FCS_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @FCS_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end