classdef FCSdir_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        pushbutton1  matlab.ui.control.Button
        pushbutton2  matlab.ui.control.Button
        checkbox1    matlab.ui.control.CheckBox
        pushbutton3  matlab.ui.control.Button
    end

    
    methods (Access = private)
        function edit1_Callback(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit1 as text
            %        str2double(get(hObject,'String')) returns contents of edit1 as a double
            
            infile = get(hObject,'string');
                     app.edit1 = infile;
        end
        
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function FCSdir_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to FCSdir (see VARARGIN)
%             
%             % Choose default command line output for FCSdir
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Callback function
        function checkbox1_Callback(app, event)
            % hObject    handle to checkbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox1
            
            trip = get(hObject,'Value');
                     app.checkbox1 = trip;
        end

        % Callback function
        function pushbutton1_Callback(app, event)
         
        end

        % Callback function
        function pushbutton2_Callback(app, event)
          
        end

        % Callback function
        function pushbutton3_Callback(app, event)
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                 infile = uigetdir;
                       
            %set(app.pushbutton3, 'UserData', infile);
                     app.pushbutton3.UserData = infile;
            %disp(hObject.String);
              % handles = guihandles(hObject);
                app.pushbutton3.Text = infile;
        end

        % Button pushed function: pushbutton3
        function pushbutton3ButtonPushed(app, event)
                             infile = uigetdir;
                       
            %set(app.pushbutton3, 'UserData', infile);
                     app.pushbutton3.UserData = infile;
            %disp(hObject.String);
              % handles = guihandles(hObject);
                app.pushbutton3.Text = infile;
        end

        % Button pushed function: pushbutton1
        function pushbutton1ButtonPushed(app, event)
               % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            trip1 = get(app.checkbox1,'Value');
            %trip1=char(trip1);
            %trip1=str2num(trip1);
            
            if trip1==1
            %Code to fit data from input file to FCSdir autocorrelation curve
            %Inputs:
            %infile is the path to the input file
            %incolumn is the column to use for the analysis (1 = Red/Acceptor channel,
            %   2 = Green/Donor channel)
            %binning is the time binning of the data to use
            %
            %Output:
            %outvector is a list of the fitted parameters from the analysis
            
            infile2 = get(app.pushbutton3,'UserData');
            infile2=char(infile2);
            indir=infile2
            incolumn=1
            binning=1;
            dirfiles = dir(indir);
            mkdir(strcat(indir,'/','FCS\'));
            outpathtot=strcat(indir,'/','FCS\','FCSonecomptripletDirectory');
            
            %Run fcsoutput for each file and save measurements in output directory
            filenamematrix=cell(0,8);
            
            for i=1:size(dirfiles,1)
                if ((~dirfiles(i).isdir) )   %Check for certain file characteristics here
                    inpathtot=strcat(indir,'/',dirfiles(i).name);
                    [~, filenames, ~] = fileparts(dirfiles(i).name);
            
                    dirfiles(i).name                              %Output the file being processed
                    outvector=fcscorrfit_1comptrip(inpathtot,incolumn);
                    fileinfo=cell(1,9);
                  % fileinfo={filenames,outvector(:,1),outvector(:,2),outvector(:,3),outvector(:,4),outvector(:,5),outvector(:,6),outvector(:,7),outvector(:,8),outvector(:,9)};
                   fileinfo={filenames,outvector(:,1),outvector(:,2),outvector(:,3),outvector(:,4),outvector(:,5),outvector(:,6),outvector(:,7)};
                    %fileinfo={filenames,outvector(:,1),outvector(:,2)};
                    filenamematrix=[filenamematrix; fileinfo];
                    %avematrix=[avematrix; outvector];
            
                end
            end
            
            
            outvecttot=filenamematrix;
            outvecttitle={'Filename','a (z0/w0)','G0','Ginf','Diffusion Time','Te','Tp','r.m.s.e.'};
            outvecttot=[outvecttitle;outvecttot];
            xlswrite(outpathtot,outvecttot);
            
            
            %dlmwrite('testautocorr',outvector,'delimiter','\t','newline','pc','precision','%.6f');
            end
            
            if trip1==0
            %Code to fit data from input file to FCSdir autocorrelation curve
            %Inputs:
            %infile is the path to the input file
            %incolumn is the column to use for the analysis (1 = Red/Acceptor channel,
            %   2 = Green/Donor channel)
            %binning is the time binning of the data to use
            %
            %Output:
            %outvector is a list of the fitted parameters from the analysis
            
            infile2 = get(app.pushbutton3,'UserData');
            infile2=char(infile2);
            indir=infile2
            incolumn=1
            binning=1;
            dirfiles = dir(indir);
            mkdir(strcat(indir,'/','FCS\'));
            outpathtot=strcat(indir,'/','FCS\','FCSonecompnotripletDirectory');
            
            %Run fcsoutput for each file and save measurements in output directory
            filenamematrix=cell(0,8);
            
            for i=1:size(dirfiles,1)
                if ((~dirfiles(i).isdir) )   %Check for certain file characteristics here
                    inpathtot=strcat(indir,'/',dirfiles(i).name);
                    [~, filenames, ~] = fileparts(dirfiles(i).name);
            
                    dirfiles(i).name                              %Output the file being processed
                    outvector=fcscorrfit_1compnotrip(inpathtot,incolumn);
                    fileinfo=cell(1,9);
                  % fileinfo={filenames,outvector(:,1),outvector(:,2),outvector(:,3),outvector(:,4),outvector(:,5),outvector(:,6),outvector(:,7),outvector(:,8),outvector(:,9)};
                   fileinfo={filenames,outvector(:,1),outvector(:,2),outvector(:,3),outvector(:,4),outvector(:,5)};
                    %fileinfo={filenames,outvector(:,1),outvector(:,2)};
                    filenamematrix=[filenamematrix; fileinfo];
                    %avematrix=[avematrix; outvector];
            
                end
            end
            
            
            outvecttot=filenamematrix;
            outvecttitle={'Filename','a (z0/w0)','G0','Ginf','Diffusion Time','r.m.s.e.'};
            outvecttot=[outvecttitle;outvecttot];
            xlswrite(outpathtot,outvecttot);
            
            
            %dlmwrite('testautocorr',outvector,'delimiter','\t','newline','pc','precision','%.6f');
            end
        end

        % Button pushed function: pushbutton2
        function pushbutton2ButtonPushed(app, event)
              % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            trip1 = get(app.checkbox1,'Value');
            %trip1=char(trip1);
            %trip1=str2num(trip1);
            
            if trip1==1
            %Code to fit data from input file to FCSdir autocorrelation curve
            %Inputs:
            %infile is the path to the input file
            %incolumn is the column to use for the analysis (1 = Red/Acceptor channel,
            %   2 = Green/Donor channel)
            %binning is the time binning of the data to use
            %
            %Output:
            %outvector is a list of the fitted parameters from the analysis
            
            infile2 = get(app.pushbutton3,'UserData');
            infile2=char(infile2);
            indir=infile2
            incolumn=1
            binning=1;
            dirfiles = dir(indir);
            mkdir(strcat(indir,'/','FCS\'));
            outpathtot=strcat(indir,'/','FCS\','FCStwocomptripletDirectory');
            
            %Run fcsoutput for each file and save measurements in output directory
            filenamematrix=cell(0,8);
            
            for i=1:size(dirfiles,1)
                if ((~dirfiles(i).isdir) )   %Check for certain file characteristics here
                    inpathtot=strcat(indir,'/',dirfiles(i).name);
                    [~, filenames, ~] = fileparts(dirfiles(i).name);
            
                    dirfiles(i).name                              %Output the file being processed
                    outvector=fcscorrfit_2comptrip(inpathtot,incolumn);
                    fileinfo=cell(1,9);
                   fileinfo={filenames,outvector(:,1),outvector(:,2),outvector(:,3),outvector(:,4),outvector(:,5),outvector(:,6),outvector(:,7),outvector(:,8),outvector(:,9)};
                   %fileinfo={filenames,outvector(:,1),outvector(:,2),outvector(:,3),outvector(:,4),outvector(:,5)};
                    %fileinfo={filenames,outvector(:,1),outvector(:,2)};
                    filenamematrix=[filenamematrix; fileinfo];
                    %avematrix=[avematrix; outvector];
            
                end
            end
            
            
            outvecttot=filenamematrix;
            outvecttitle={'Filename','a (z0/w0)', 'Fraction Component 1' 'G0','Ginf','Component 1 Diffusion Time','Component 2 Diffusion Time','Te','Tp','r.m.s.e.'};
            outvecttot=[outvecttitle;outvecttot];
            xlswrite(outpathtot,outvecttot);


            end
            
            if trip1==0
            %Code to fit data from input file to FCSdir autocorrelation curve
            %Inputs:
            %infile is the path to the input file
            %incolumn is the column to use for the analysis (1 = Red/Acceptor channel,
            %   2 = Green/Donor channel)
            %binning is the time binning of the data to use
            %
            %Output:
            %outvector is a list of the fitted parameters from the analysis
            
            %Code to fit data from input file to FCSdir autocorrelation curve
            %Inputs:
            %infile is the path to the input file
            %incolumn is the column to use for the analysis (1 = Red/Acceptor channel,
            %   2 = Green/Donor channel)
            %binning is the time binning of the data to use
            %
            %Output:
            %outvector is a list of the fitted parameters from the analysis
            
            infile2 = get(app.pushbutton3,'UserData');
            infile2=char(infile2);
            indir=infile2
            incolumn=1
            binning=1;
            dirfiles = dir(indir);
            
            mkdir(strcat(indir,'/','FCS\'));
            outpathtot=strcat(indir,'/','FCS\','FCStwocompnotripletDirectory');
            
            %Run fcsoutput for each file and save measurements in output directory
            filenamematrix=cell(0,8);
            
            for i=1:size(dirfiles,1)
                if ((~dirfiles(i).isdir) )   %Check for certain file characteristics here
                    inpathtot=strcat(indir,'/',dirfiles(i).name);
                    [~, filenames, ~] = fileparts(dirfiles(i).name);
            
                    dirfiles(i).name                              %Output the file being processed
                    outvector=fcscorrfit_2compnotrip(inpathtot,incolumn);
                    fileinfo=cell(1,9);
                   fileinfo={filenames,outvector(:,1),outvector(:,2),outvector(:,3),outvector(:,4),outvector(:,5),outvector(:,6),outvector(:,7)};
                   %fileinfo={filenames,outvector(:,1),outvector(:,2),outvector(:,3),outvector(:,4),outvector(:,5)};
                    %fileinfo={filenames,outvector(:,1),outvector(:,2)};
                    filenamematrix=[filenamematrix; fileinfo];
                    %avematrix=[avematrix; outvector];
            
                end
            end
            
            
            outvecttot=filenamematrix;
            outvecttitle={'Filename','a (z0/w0)', 'Fraction Component 1' 'G0','Ginf','Component 1 Diffusion Time','Component 2 Diffusion Time','r.m.s.e.'};
            outvecttot=[outvecttitle;outvecttot];
            xlswrite(outpathtot,outvecttot);


            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'FCSdir';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1ButtonPushed, true);
            app.pushbutton1.Text = 'One Species';
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2ButtonPushed, true);
            app.pushbutton2.Text = 'Two Species';
            app.checkbox1 = uicheckbox(app.figure1);
            app.checkbox1.Text = 'Correction for Triplet State';
            app.checkbox1.Value = true;
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3ButtonPushed, true);
            app.pushbutton3.Text = 'Choose Directory';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FCSdir_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @FCSdir_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end