classdef brightness_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        pushbutton1  matlab.ui.control.Button
        pushbutton2  matlab.ui.control.Button
        pushbutton3  matlab.ui.control.Button
        edit2        matlab.ui.control.EditField
    end

    
    methods (Access = private)
        function edit1_Callback(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit1 as text
            %        str2double(get(hObject,'String')) returns contents of edit1 as a double
            
            %filename
            infile = get(hObject,'string');
                     app.edit1 = infile;
        end
        
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function brightness_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to brightness (see VARARGIN)
%             
%             % Choose default command line output for brightness
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit2 as text
            %        str2double(get(hObject,'String')) returns contents of edit2 as a double
            
            bins = get(app.edit2,'Value');
                    % app.edit2 = bins;
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            infile2 = get(app.pushbutton2,'UserData');
            infile2=char(infile2);
            %run fileave
            indata=dlmread(infile2,'\t');
            [filepath,name,ext] = fileparts(infile2)
            infile=name
            inpath=filepath
            %Get columns of donor and acceptor signals
            acceptor=indata(:,1);
            %donor=indata(:,2);
            
            %Smooth signal
            
            %donorback=smooth(donor,500);
            %acceptorback=smooth(acceptor,500);
            
            %donor=donor-donorback;
            %acceptor=acceptor-acceptorback;
            
            %acceptor=acceptor(1:1000);
            %donor=donor(1:1000);
            
            %Calculate background level from signal average
            acceptormean=mean(acceptor);
            %donormean=mean(donor);
            
            %donorstdev=std(donor);
            acceptorstdev=std(acceptor);
            
            %donorskew=(std(donor)^2)/mean(donor);
            acceptorskew=(std(acceptor)^2)/mean(acceptor);
            
            %donormode=mode(donor);
            acceptormode=mode(acceptor);
            
            acceptorbrightness=((std(acceptor)^2)/mean(acceptor))-1;
            
            outvector=[acceptormean, acceptorstdev,acceptormode,acceptorskew,acceptorbrightness];
            outvector=num2cell(outvector);
            outvecttot={'Average Signal','StDev','Mode','B Parameter','Brightness'};
            outvecttot=[outvecttot;outvector];
            outpath=strcat(filepath,'/brightness/',name);
            mkdir(filepath,'/brightness/')
            T=array2table(outvecttot)
            writetable(T,outpath,'Delimiter','tab');
           
            
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [filename, pathname] = uigetfile('*.*');
            infile = strcat(pathname,filename);
            set(app.pushbutton2, 'UserData', infile);
               %      app.pushbutton2 = infile;
            %disp(hObject.String);
            %    handles = guihandles(hObject);
                app.pushbutton2.Text = filename;
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %% Import your data
            infile2 = get(app.pushbutton2,'UserData');
            infile2=char(infile2);
            
            bins2 = get(app.edit2,'Value');
            bins2=char(bins2);
            bins2=str2num(bins2);
            %run fileave
            indata=dlmread(infile2,'\t');
            [filepath,name,ext] = fileparts(infile2)
            infile=name
            inpath=filepath
            acceptor=indata(:,1);
            %donor=indata(:,2);
            outpathtot=strcat(filepath,'/figures/',name,'histogram');
            mkdir(filepath,'/figures/')
            %outpathtot=strcat(inpath,'./',infile,'histogram')
             %% Plot
             figure;
            hold on;
            h= histogram(acceptor,'BinWidth',bins2)
            h.FaceColor='g'
            h.EdgeColor='g'
            set(gca,'YScale','log')
            xlabel('Counts (photons/ms)')
            ylabel('Occurance')
            title([name, ' histogram'], 'Interpreter', 'none')
            hold off
            %
             export_fig(outpathtot,'-pdf','-append')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'brightness';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Text = 'Run';
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Text = 'Choose File';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Text = 'Photon Counting Histogram';
            app.edit2 = uieditfield(app.figure1, 'text');
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Value = 'Bin Width';
        end
    end

    methods (Access = public)

        % Construct app
        function app = brightness_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @brightness_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end