classdef brightnessdir_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        pushbutton1  matlab.ui.control.Button
        pushbutton2  matlab.ui.control.Button
        pushbutton3  matlab.ui.control.Button
        edit2        matlab.ui.control.EditField
    end

    
    methods (Access = private)
        function edit1_Callback(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit1 as text
            %        str2double(get(hObject,'String')) returns contents of edit1 as a double
            
            %filename
            infile = get(hObject,'string');
                     app.edit1 = infile;
        end
        
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function brightnessdir_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to brightnessdir (see VARARGIN)
%             
%             % Choose default command line output for brightnessdir
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit2 as text
            %        str2double(get(hObject,'String')) returns contents of edit2 as a double
                    bins=app.edit2.Value
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            indir = get(app.pushbutton2,'UserData');
            indir=char(indir);
            
            %Code for working with all files in directory.
            dirfiles = dir(indir);
            
            
            mkdir(strcat(indir,'/brightness\'));
            outpathtot=strcat(indir,'/brightness\','brightnessdirectory');
            
            %Run fileave for each file and save measurements in output directory
            filenamematrix={};
            avematrix=zeros(0,2);
            for i=1:size(dirfiles,1)
                if ((~dirfiles(i).isdir) )   %Check for certain file characteristics here
                    inpathtot=strcat(indir,'\',dirfiles(i).name);
                    [~, filenames, ~] = fileparts(dirfiles(i).name);
            
                    dirfiles(i).name                              %Output the file being processed
                    outvector=fileave1col(inpathtot);
            
            
                    %fileinfo=cell(1,7);
                    outvector=num2cell(outvector);
                    fileinfo=horzcat(filenames,outvector);%mat2cell(outvector,size(outvector,1))};
            
            
                    filenamematrix=[filenamematrix; fileinfo];
                    %avematrix=[avematrix; outvector];
            
                end
            end
            
            outvecttot={'File name','Average Signal','StDev','Mode','B Parameter','Brightness'};
            outvecttot=[outvecttot;filenamematrix]
            xlswrite(outpathtot,outvecttot);
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


                 infile = uigetdir;
                       
            %set(app.pushbutton3, 'UserData', infile);
                     app.pushbutton2.UserData = infile;
            %disp(hObject.String);
              % handles = guihandles(hObject);
                app.pushbutton2.Text = infile;
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %%
            %Code for working with all files in directory.
            indir = get(app.pushbutton2,'UserData');
            indir=char(indir);
            
            bins2 = get(app.edit2,'Value');
            bins2=char(bins2);
            bins2=str2num(bins2);
            
            dirfiles = dir(indir);
            %Run fileave for each file and save measurements in output directory
            filenamematrix=cell(0,1);
            
            for i=1:size(dirfiles,1)
                if ((~dirfiles(i).isdir) )   %Check for certain file characteristics here
                    inpathtot=strcat(indir,'\',dirfiles(i).name);
                    [~, filenames, ~] = fileparts(dirfiles(i).name);
            
                    fileinfo={filenames};
            
                    filenamematrix=[filenamematrix; fileinfo];
                    input_list=strcat(filenamematrix,'.pdf')
            
                    dirfiles(i).name                              %Output the file being processed
                    plotfig1col(inpathtot,bins2)
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'brightnessdir';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Text = 'Run';
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Text = 'Choose Directory';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Text = 'Photon Counting Histogram';
            app.edit2 = uieditfield(app.figure1, 'text');
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Value = 'Bin Width';
        end
    end

    methods (Access = public)

        % Construct app
        function app = brightnessdir_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @brightnessdir_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end