classdef peakpickdir_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        pushbutton1  matlab.ui.control.Button
        pushbutton2  matlab.ui.control.Button
        edit2        matlab.ui.control.EditField
        text3        matlab.ui.control.Label
        text4        matlab.ui.control.Label
        text5        matlab.ui.control.Label
        pushbutton3  matlab.ui.control.Button
        pushbutton4  matlab.ui.control.Button
    end

    
    methods (Access = private)
        function edit1_Callback(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit1 as text
            %        str2double(get(hObject,'String')) returns contents of edit1 as a double
            infile = get(hObject,'string');
                     app.edit1 = infile;
        end
        
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function peakpickdir_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to peakpickdir (see VARARGIN)
%             
%             % Choose default command line output for peakpickdir
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit2 as text
            %        str2double(get(hObject,'String')) returns contents of edit2 as a double
            
thresh=app.edit2.Value;
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            infile2 = get(app.pushbutton4,'UserData');
            infile2=char(infile2);
            
            thresh2 = get(app.edit2,'Value');
            thresh2=char(thresh2);
            thresh2=str2num(thresh2);
            
            %Code for calling ASCANALYSIS for all files in directory.
            
            dirfiles = dir(infile2);
            
            %Place wanted file names into array
            %for n=1:size(dirfiles,1)
            %    [pathstr, name, ext, versn] = fileparts(dirfiles(n).name);
            
            %        if findstr(dirfiles(n).name,needstring)
            %            files=cat(1,files,cellstr(dirfiles(n).name));
            %        end
            %    end
            %end
            
            mkdir(strcat(infile2,'\','PeakPicking/'));
            outpathtot=strcat(infile2,'\','PeakPicking/','peakpickuserthreshdirectory');
            %Run tjumpintegrate for each file and save measurements in output directory
            filenamematrix=cell(0,6);
            avematrix=zeros(0,2);
            outvectortest=zeros(0,500)
            for i=1:size(dirfiles,1)
                if ((~dirfiles(i).isdir) )   %Check for certain file characteristics here
                    inpathtot=strcat(infile2,'\',dirfiles(i).name);
                    [~, filenames,~] = fileparts(dirfiles(i).name);
            
                    dirfiles(i).name                              %Output the file being processed
                    outvector=peakpickmanthresh(inpathtot,thresh2);
            %        fileinfo=cell(1,4);
                    fileinfo={filenames,outvector(1),outvector(2),outvector(3),outvector(4),outvector(5),outvector(6)};
            %        outvector = padarray(outvector,[0,(500-size(outvector,2))],'post')
            %        outvectortemp=horzcat(outvector);
            %        outvectortest=vertcat(outvectortest,outvectortemp);
                    filenamematrix=[filenamematrix; fileinfo];
                    %avematrix=[avematrix; outvector];
            
                end
            end
            
            
            outvecttot=filenamematrix;
            outvecttitle={'Filename','Peak Number','Mean','B Parameter','Integrated Intensity','Average Peak Height','Total Time Above Threshold'};
            outvect2=[outvecttitle;outvecttot];
            %outvecttot=outvectortest
            xlswrite(outpathtot,outvect2);
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            infile2 = get(app.pushbutton4,'UserData');
            infile2=char(infile2);
            
            %%
            %Code for calling ASCANALYSIS for all files in directory.
            
            dirfiles = dir(infile2);
            
            %Place wanted file names into array
            %for n=1:size(dirfiles,1)
            %    [pathstr, name, ext, versn] = fileparts(dirfiles(n).name);
            
            %        if findstr(dirfiles(n).name,needstring)
            %            files=cat(1,files,cellstr(dirfiles(n).name));
            %        end
            %    end
            %end
            mkdir(strcat(infile2,'\','PeakPicking/'));
            outpathtot=strcat(infile2,'\','PeakPicking/','peakpickmean3sdthreshdirectory');
            %Run tjumpintegrate for each file and save measurements in output directory
            filenamematrix=cell(0,6);
            avematrix=zeros(0,2);
            outvectortest=zeros(0,500)
            for i=1:size(dirfiles,1)
                if ((~dirfiles(i).isdir) )   %Check for certain file characteristics here
                    inpathtot=strcat(infile2,'\',dirfiles(i).name);
                    [~, filenames,~] = fileparts(dirfiles(i).name);
            
                    dirfiles(i).name                              %Output the file being processed
                    outvector=peakpickautothresh(inpathtot);
            %        fileinfo=cell(1,4);
                    fileinfo={filenames,outvector(1),outvector(2),outvector(3),outvector(4),outvector(5),outvector(6)};
            %        outvector = padarray(outvector,[0,(500-size(outvector,2))],'post')
            %        outvectortemp=horzcat(outvector);
            %        outvectortest=vertcat(outvectortest,outvectortemp);
                    filenamematrix=[filenamematrix; fileinfo];
                    %avematrix=[avematrix; outvector];
            
                end
            end
            
            
            outvecttot=filenamematrix;
            outvecttitle={'Filename','Peak Number','Mean','B Parameter','Integrated Intensity','Average Peak Height','Total Time Above Threshold'};
            outvect2=[outvecttitle;outvecttot];
            %outvecttot=outvectortest
            xlswrite(outpathtot,outvect2);
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            infile2 = get(app.pushbutton4,'UserData');
            infile2=char(infile2);
            
            %Code for working with all files in directory.
            dirfiles = dir(infile2);
            %Run fileave for each file and save measurements in output directory
            filenamematrix=cell(0,1);
            
            for i=1:size(dirfiles,1)
                if ((~dirfiles(i).isdir) )   %Check for certain file characteristics here
                    inpathtot=strcat(infile2,'\',dirfiles(i).name);
                    [~, filenames, ~] = fileparts(dirfiles(i).name);
            
                    fileinfo={filenames};
            
                    filenamematrix=[filenamematrix; fileinfo];
                    input_list=strcat(filenamematrix,'.pdf')
            
                    dirfiles(i).name                              %Output the file being processed
                    plotfigraw1col(inpathtot)
                end
            end
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
                
                 infile = uigetdir;
                       
            %set(app.pushbutton3, 'UserData', infile);
                     app.pushbutton4.UserData = infile;
            %disp(hObject.String);
              % handles = guihandles(hObject);
                app.pushbutton4.Text = infile;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'peakpickdir';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Text = 'Give me numbers';
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Text = 'Give me numbers';
            app.edit2 = uieditfield(app.figure1, 'text');
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Value = 'Enter Lower Threshold';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'mean + 3 s.d.';
            app.text4 = uilabel(app.figure1);
            app.text4.Text = 'user defined';
            app.text5 = uilabel(app.figure1);
            app.text5.Text = 'choose your threshold';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Text = 'plot';
            app.pushbutton4 = uibutton(app.figure1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Text = 'Choose Directory';
        end
    end

    methods (Access = public)

        % Construct app
        function app = peakpickdir_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @peakpickdir_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end